classdef Figure_Recognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        FrameRateLabel  matlab.ui.control.Label
        Image           matlab.ui.control.Image
        Button_3        matlab.ui.control.Button
        Button          matlab.ui.control.Button
    end

    
    properties (Access = public)
        Property % Description
        camera;
        timer;
        lastFrameTime;
        frameCount=0;
        ImageNow;
        Number_net;
        Number_net_179;
    end
    
    methods (Access = private)
       
        % 定义更新帧的函数
        function updateFrame(app)
%             frame = snapshot(app.camera);  % 获取当前摄像头的帧
%     
%             % 如果图像数据类型不是 uint8，进行转换以提高性能
%             if ~isa(frame, 'uint8')
%                 frame = im2uint8(frame);  % 转换图像为 uint8 格式
%             end
%         
%             % 更新 Image 控件的图像源
%             app.Image.ImageSource = frame;
%         
%             % 减少 drawnow 调用频率以防止频繁刷新导致卡顿
%             persistent lastDrawTime;
%             if isempty(lastDrawTime) || toc(lastDrawTime) > 0.03  % 每0.1秒调用一次 drawnow
%                 drawnow;
%                 lastDrawTime = tic;
%             end
%         
%             %% 计算帧率
%             app.frameCount = app.frameCount + 1;
%             elapsedTime = toc(app.lastFrameTime);  % 获取时间间隔
%             if elapsedTime >= 1  % 每秒更新一次帧率
%                 frameRate = app.frameCount / elapsedTime;  % 计算帧率
%                 app.FrameRateLabel.Text = sprintf('Frame Rate: %.2f FPS', frameRate);  % 更新帧率显示
%                 app.frameCount = 0;  % 重置帧计数
%                 app.lastFrameTime = tic;  % 重置计时器
%             end
            
            frame = snapshot(app.camera);  % 获取当前摄像头的帧

            % 如果图像数据类型不是 uint8，进行转换以提高性能
            if ~isa(frame, 'uint8')
                frame = im2uint8(frame);  % 转换图像为 uint8 格式
            end
        
            % 获取图像的尺寸
            [frameHeight, frameWidth, ~] = size(frame);
            
            % 定义中心正方形区域的边长（例如 200 像素）
            squareSize = 200;
            
            % 计算正方形区域的左上角坐标
            topLeftX = round((frameWidth - squareSize) / 2);
            topLeftY = round((frameHeight - squareSize) / 2);
            
            % 提取正方形区域的图像
            squareRegion = frame(topLeftY:(topLeftY + squareSize - 1), topLeftX:(topLeftX + squareSize - 1), :);
            
            % 将该区域转换为灰度图
            graySquareRegion = rgb2gray(squareRegion);
            
            % 对灰度图进行二值化处理
            binarySquareRegion = imbinarize(graySquareRegion);
            
            % 将二值化的图像转换为三通道（RGB）
            binarySquareRegionRGB = uint8(cat(3, binarySquareRegion * 255, binarySquareRegion * 255, binarySquareRegion * 255));
            
            % 将处理后的区域放回原图像
            frame(topLeftY:(topLeftY + squareSize - 1), topLeftX:(topLeftX + squareSize - 1), :) = binarySquareRegionRGB;
            
            % 绘制黑色方框
            frame = insertShape(frame, 'Rectangle', [topLeftX, topLeftY, squareSize, squareSize], 'LineWidth', 5, 'Color', 'black');
        
            % 更新 Image 控件的图像源
            app.Image.ImageSource = frame;
        
            % 控制识别判断频率，每1秒进行一次
            persistent lastRecognitionTime;  % 持久变量，用于记录上次识别的时间
            persistent lastLabelText;  % 持久变量，保存上一次的识别结果文本
        
            if isempty(lastRecognitionTime)
                lastRecognitionTime = tic;  % 初始化计时器
            end
            if isempty(lastLabelText)
                lastLabelText = 'Waiting for first recognition...';  % 初始化识别结果文本
            end
        
            elapsedRecognitionTime = toc(lastRecognitionTime);  % 计算自上次识别以来经过的时间
            if elapsedRecognitionTime >= 1  % 每1秒进行一次识别
                lastRecognitionTime = tic;  % 重置计时器
                
                %% 手写数字识别
                %% 腐蚀膨胀
                %% 创建一个形态学结构元
                se = strel('disk', 3);
                % 对二值图像进行膨胀操作，增强笔迹宽度
                Origin_dilatedImage = imdilate(~binarySquareRegion, se);
                % 调整提取区域大小为28x28
                Resize_Image = imresize(Origin_dilatedImage, [28 28], 'bicubic');
                
                % 对调整后的图像进行二值化处理
%                 finalImage = imbinarize(Resize_Image, graythresh(Resize_Image));
                finalImage = Resize_Image;
                
                %% 创建一个形态学结构元
                se = strel('disk', 1);
                % 对二值图像进行膨胀操作，增强笔迹宽度
                dilatedImage = imdilate(finalImage, se);
                
                finalImage_double = double(dilatedImage);
                % 处理图像并将其传递给网络
                imageArray = reshape(finalImage_double, [28, 28, 1]);  % 将二值化后的图像调整为28x28x1的三维数组
                [predictedLabel, scores] = classify(app.Number_net, imageArray);  % 使用训练好的神经网络进行分类
                confidence = max(scores);  % 置信度，即分类器对该类别的预测得分
                    if char(predictedLabel) == '1' || char(predictedLabel) == '4' || char(predictedLabel) == '7' || char(predictedLabel) == '9'
                        [predictedLabel, scores] = classify(app.Number_net_179, imageArray);  % 使用训练好的神经网络进行分类
                        confidence = max(scores);  % 置信度，即分类器对该类别的预测得分
                        % 将categorical类型转为字符串数组
                        strArray = string(predictedLabel);
                        % 将字符串数组转为double数组
                        predictedLabel = str2double(strArray) + '0' -1;
                    end
                %% 更新识别结果和置信度
                lastLabelText = sprintf('Label: %s (Confidence: %.2f%%)', char(predictedLabel), confidence * 100);  % 保存本次的识别结果
            end
            
            %% 显示上一次的识别结果
            positionText = [topLeftX, max(topLeftY - 30, 10)];  % 设置文本显示位置，放在方框的左上方，不超出图像范围
            frame = insertText(frame, positionText, lastLabelText, 'FontSize', 18, 'BoxColor', 'black', 'BoxOpacity', 0.6, 'TextColor', 'white');
            
%             imshow(Resize_Image,"Parent",app.UIAxes_2);
            % 更新 Image 控件的图像源
            app.Image.ImageSource = frame;
        
            % 减少 drawnow 调用频率以防止频繁刷新导致卡顿
            persistent lastDrawTime;
            if isempty(lastDrawTime) || toc(lastDrawTime) > 0.03  % 每0.03秒调用一次 drawnow
                drawnow;
                lastDrawTime = tic;
            end
        
            %% 计算帧率
            app.frameCount = app.frameCount + 1;
            elapsedTime = toc(app.lastFrameTime);  % 获取时间间隔
            if elapsedTime >= 1  % 每秒更新一次帧率
                frameRate = app.frameCount / elapsedTime;  % 计算帧率
                app.FrameRateLabel.Text = sprintf('Frame Rate: %.2f FPS', frameRate);  % 更新帧率显示
                app.frameCount = 0;  % 重置帧计数
                app.lastFrameTime = tic;  % 重置计时器
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 设置 Image 控件为全黑图像
            blackImage = uint8(zeros(360, 640, 3));  % 创建全黑的 640x360 图像
            app.Image.ImageSource = blackImage;
            
            app.Number_net = load("CNN_Number_Test8_MNIST.mat");
            app.Number_net_179 = load("CNN_Number_Test8_MNIST_1479.mat");
            
            app.Number_net = app.Number_net.net;
            app.Number_net_179 = app.Number_net_179.net;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %% 检查可用的摄像头列表
            cameraList = webcamlist;  % 列出系统中的所有摄像头
            disp('可用的摄像头:');
            disp(cameraList);
        
%             %% 选择摄像头
%             if isempty(cameraList)
%                 error('未检测到任何摄像头，请检查摄像头驱动。');
%             else
%                 cam = webcam(1);  % 使用默认摄像头
%                 app.camera = cam;
%             end
            
            if isempty(app.camera) || ~isvalid(app.camera)
                % 初始化摄像头资源
                cameraList = webcamlist;  % 列出系统中的所有摄像头
                if isempty(cameraList)
                    error('未检测到任何摄像头，请检查摄像头驱动。');
                else
                    app.camera = webcam(1);  % 使用默认摄像头
                end
            end
        
            %% 创建 Image 控件用于显示图像
            if isempty(app.Image)  % 如果未创建，则创建 Image 控件
                app.Image = uiimage(app.UIFigure);
                % 调整 Image 控件的大小以适应摄像头的分辨率 640x360
                app.Image.Position = [100, 100, 640, 360];  % 设置控件位置和大小
            end

            % 只创建定时器一次
            if isempty(app.timer) || ~isvalid(app.timer)
                app.timer = timer('ExecutionMode', 'fixedRate', 'Period', 0.025, ...
                                  'TimerFcn', @(~,~) updateFrame(app));
            end
            
            %% 创建一个标签用于显示帧率
            if isempty(app.FrameRateLabel)  % 如果未创建标签，则创建
                app.FrameRateLabel = uilabel(app.UIFigure);
                app.FrameRateLabel.Position = [-157, 655, 200, 30];  % 设置标签位置
                app.FrameRateLabel.Text = 'Frame Rate: 0 FPS';  % 初始帧率文本
            end
        
            %% 设置定时器实现实时预览
            app.lastFrameTime = tic;  % 初始化帧时间
            app.frameCount = 0;  % 初始化帧计数
            app.timer = timer('ExecutionMode', 'fixedRate', 'Period', 0.02, ... % 降低刷新频率到10 FPS
                              'TimerFcn', @(~,~) updateFrame(app));
                              
            start(app.timer);  % 启动定时器
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if ~isempty(app.timer) && isvalid(app.timer)
                stop(app.timer);  % 停止定时器
            end
            
            if ~isempty(app.camera) && isvalid(app.camera)
                clear app.camera;  % 清除摄像头对象
            end
            
            % 设置 Image 控件为全黑图像
            blackImage = uint8(zeros(360, 640, 3));  % 创建全黑的 640x360 图像
            app.Image.ImageSource = blackImage;
        end

        % Callback function
        function Button_2Pushed(app, event)
            %% 捕捉一帧图像
            cam = app.camera;
            pause(2);  % 等待2秒，确保摄像头准备好
            captured_Image = snapshot(cam);  % 拍照并捕获图像
            app.ImageNow = captured_Image;
        
            %% 显示捕获的图像
            imshow(captured_Image, "Parent", app.UIAxes_2);
        
            %% 隐藏坐标轴
            set(app.UIAxes_2, 'XTick', [], 'YTick', []);
            app.UIAxes_2.XColor = 'none';  % 隐藏X轴
            app.UIAxes_2.YColor = 'none';  % 隐藏Y轴
            
            %% 设置标题
            title(app.UIAxes_2, '图像保存成功', 'FontSize', 12);
        
            %% 保存图像到文件
            imwrite(captured_Image, 'E:\模式识别\Pictrure_test\TestPicture.jpg');
            disp('图像已保存为 captured_image_from_tablet.jpg');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.timer) && isvalid(app.timer)
                stop(app.timer);  % 停止定时器
            end
            
            if ~isempty(app.camera) && isvalid(app.camera)
                clear app.camera;  % 清除摄像头对象
            end
            
            % 设置 Image 控件为全黑图像
            blackImage = uint8(zeros(360, 640, 3));  % 创建全黑的 640x360 图像
            app.Image.ImageSource = blackImage;
            % 释放定时器资源
            if ~isempty(app.timer) && isvalid(app.timer)
                stop(app.timer);   % 停止定时器
                delete(app.timer); % 删除定时器
                app.timer = [];    % 清空定时器引用
            end
            
            % 释放摄像头资源
            if ~isempty(app.camera) && isvalid(app.camera)
                clear app.camera;  % 清除摄像头对象
            end
            
            % 删除 UI Figure（关闭应用程序）
            delete(app.UIFigure);
            delete(app);
        end

        % Callback function
        function Button_4Pushed(app, event)
            %% NO FUNCTION
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = {'打开摄像头'; ''};
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '关闭摄像头';
            app.Image = uiimage(app.UIFigure);
            app.FrameRateLabel = uilabel(app.UIFigure);
            app.FrameRateLabel.Text = 'Frame Rate：0.00 FPS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Figure_Recognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end